classdef Interface_Modulation_Amplitude < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        domainetemporelTab              matlab.ui.container.Tab
        Nombredepoints                  matlab.ui.control.NumericEditField
        NombredepointsEditFieldLabel    matlab.ui.control.Label
        Frequencedechantillonage        matlab.ui.control.NumericEditField
        FrequencedechantHzLabel         matlab.ui.control.Label
        Dure                            matlab.ui.control.NumericEditField
        DuresLabel                      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        enveloppeButton                 matlab.ui.control.StateButton
        modulationButtonGroup           matlab.ui.container.ButtonGroup
        figure_2_avec_port              matlab.ui.control.ToggleButton
        figure_2_sans_port              matlab.ui.control.ToggleButton
        TabGroup2                       matlab.ui.container.TabGroup
        messageTab                      matlab.ui.container.Tab
        message                         matlab.ui.control.UIAxes
        signalsecondairemodulTab        matlab.ui.container.Tab
        signal_modulee                  matlab.ui.control.UIAxes
        figure_1                        matlab.ui.control.Button
        amplitude_porteuse              matlab.ui.control.NumericEditField
        amplitudedelaporteuseEditFieldLabel  matlab.ui.control.Label
        taux_m                          matlab.ui.control.NumericEditField
        tauxdemodulationEditFieldLabel  matlab.ui.control.Label
        frequence_porteuse              matlab.ui.control.NumericEditField
        frequencedelaporteuseHzLabel    matlab.ui.control.Label
        chemin_fichier_OUvariable       matlab.ui.control.TextArea
        optionsButtonGroup              matlab.ui.container.ButtonGroup
        logic_chemin                    matlab.ui.control.RadioButton
        logic_variable                  matlab.ui.control.RadioButton
        IntroduirelemessageLabel        matlab.ui.control.Label
        domainefrequentielTab           matlab.ui.container.Tab
        puissance_2                     matlab.ui.control.NumericEditField
        PuissanceSSBVSBWLabel           matlab.ui.control.Label
        filtrSSBVSBCheckBox             matlab.ui.control.CheckBox
        modulCheckBox                   matlab.ui.control.CheckBox
        modulantCheckBox                matlab.ui.control.CheckBox
        type_mod_bande                  matlab.ui.control.DropDown
        type_mod                        matlab.ui.control.DropDown
        puissance                       matlab.ui.control.NumericEditField
        PuissanceSCFCWLabel             matlab.ui.control.Label
        bande                           matlab.ui.control.NumericEditField
        BandeenvoyeHzLabel              matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        specter                         matlab.ui.control.UIAxes
        demodulationTab                 matlab.ui.container.Tab
        Button_4                        matlab.ui.control.Button
        spectreslorsdeladmodulationLabel  matlab.ui.control.Label
        departCheckBox                  matlab.ui.control.CheckBox
        EcouterSdepart                  matlab.ui.control.Button
        Ecouterv                        matlab.ui.control.Button
        vCheckBox                       matlab.ui.control.CheckBox
        yCheckBox                       matlab.ui.control.CheckBox
        xCheckBox                       matlab.ui.control.CheckBox
        sCheckBox                       matlab.ui.control.CheckBox
        TabGroup3                       matlab.ui.container.TabGroup
        DomainetemporelTab              matlab.ui.container.Tab
        domainetemps                    matlab.ui.control.UIAxes
        DomainefrquentielTab            matlab.ui.container.Tab
        domainefreq                     matlab.ui.control.UIAxes
        CocherpourafficherlessignauxetleursLabel  matlab.ui.control.Label
        type_mod_2                      matlab.ui.control.DropDown
        Dphi                            matlab.ui.control.NumericEditField
        DphiLabel                       matlab.ui.control.Label
        Df                              matlab.ui.control.NumericEditField
        DfHzLabel                       matlab.ui.control.Label
        Apcos2pifpDfDphiLabel           matlab.ui.control.Label
        vtLabel                         matlab.ui.control.Label
        ytLabel                         matlab.ui.control.Label
        xtLabel                         matlab.ui.control.Label
        stLabel                         matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        Label_4                         matlab.ui.control.Label
    end

%             Fait par:
%             Ahmed Amrani Akdi


    properties (Access = public)
        modulation = 0 % prevenir erreur mod avant tracage
        
        %%%%%%%%%%%%%%%%%%%%%%% domaine temps
        t % temps
        x % le message
        sport % signal modulee avec porteuse
        snoport % signal modulee sans porteuse
        %s % signal secondaire modulee
        env_sport % enveloppe calculé sport
        env_snoport % enveloppe calculé snoport
        %env_plot % enveloppe plot
        env_plot_sport
        env_plot_snoport
        %env_control = 0 % enveloppe control button (recalcul)
        %env_control2 = 0 % enveloppe control button 2 ( visible == off)
        
        control_calcul_modulee = 1
        %control_calcul_env = 1
        
        plot_sport
        plot_snoport
        
        %%%%%%%%%%%%%%%% domaine frequence
        control_calcul_fft = 1 % control de recalcul
        f % frequence
        spectre_signal
        spectre_signal_sport
        spectre_signal_snoport
        spectre_signal_snoport_SSB
        spectre_signal_snoport_VSB
        
        plot_spectre_signal
        plot_spectre_signal_sport
        plot_spectre_signal_snoport
        plot_spectre_signal_snoport_SSB
        plot_spectre_signal_snoport_VSB
        
        band_lo
        band_hi
        bandw
        
        power_sport
        power_snoport
        power_snoport_SSB
        power_snoport_VSB
        %%%%%%%%modulee+filtre en temps
        
        snoport_SSB
        snoport_VSB
        
        control_calcul_demod = 1
        
        snp
        sp
        xnp
        xp
        ynp
        yp
        vnp
        vp
        
        Snp
        Sp
        Xnp
        Xp
        Ynp
        Yp
        Vnp
        Vp
        
        plot_snp
        plot_sp
        plot_xnp
        plot_xp
        plot_ynp
        plot_yp
        plot_vnp
        plot_vp
        plot_x
        
        plot_Snp
        plot_Sp
        plot_Xnp
        plot_Xp
        plot_Ynp
        plot_Yp
        plot_Vnp
        plot_Vp
        plot_X
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: figure_1
        function figure_1ButtonPushed(app, event)
            app.control_calcul_fft = 1;
            app.control_calcul_modulee = 1;
            %app.control_calcul_env = 1;
%             app.sport = [];
%             app.snoport = [];
            a = app.optionsButtonGroup.SelectedObject;
            fun_chemin = app.chemin_fichier_OUvariable.Value{1};
            
            if strcmp(fun_chemin,'')
                return
            end
            
            if a == app.logic_chemin
                try
                    [app.x,Fe] = audioread(fun_chemin); 
                catch ME
                    uialert(app.UIFigure,ME.message,'Alert');
                    return
                end
                app.modulation = 1;
                set(app.Frequencedechantillonage,'Value',Fe);
                t_ = (0:1/Fe:(length(app.x)-1)/Fe)';
                app.Nombredepoints.Value = length(app.x);
                set(app.Dure,'Value',t_(end));
            end
            if a == app.logic_variable 
                syms modulant(t_);
                fun = strrep(fun_chemin,'t','t_');
                try
                    modulant = str2sym(fun);
                catch ME
                    uialert(app.UIFigure,ME.message,'Alert');
                    return
                end
                app.modulation = 1;
                t_ = (0:1/app.Frequencedechantillonage.Value:app.Dure.Value)'; % controler le nombre de points? pour memoire!!!
                app.x = double(subs(modulant));
                app.Nombredepoints.Value = length(t_);
            end
            app.t = t_;
            plot(app.message,app.t,app.x);
            %mouse position for data cursor? 
            modulationButtonGroupSelectionChanged(app, event);
        end

        % Callback function
        function panButtonValueChanged(app, event)
            value = app.panButton.Value;
            value2 = app.panButton_2.Value;
            value3 = app.panButton_3.Value;
            val = value || value2 || value3;
            if val == 1
                pan(app.signal_modulee,'on'); 
                pan(app.message,'on');
                pan(app.specter,'on');
                pan(app.domainetemps,'on');
                pan(app.domainefreq,'on');
            end
            if val == 0
                pan(app.specter,'off'); 
                pan(app.signal_modulee,'off'); 
                pan(app.message,'off');
                pan(app.domainetemps,'off');
                pan(app.domainefreq,'off');
                
                app.domainetemps.XLimMode = 'auto';
                app.domainetemps.YLimMode = 'auto';
                app.domainetemps.ZLimMode = 'auto';  
                
                app.domainefreq.XLimMode = 'auto';
                app.domainefreq.YLimMode = 'auto';
                app.domainefreq.ZLimMode = 'auto';
                
                app.signal_modulee.XLimMode = 'auto';
                app.signal_modulee.YLimMode = 'auto';
                app.signal_modulee.ZLimMode = 'auto';  
                
                app.message.XLimMode = 'auto';
                app.message.YLimMode = 'auto';
                app.message.ZLimMode = 'auto';
                
                app.specter.XLimMode = 'auto';
                app.specter.YLimMode = 'auto';
                app.specter.ZLimMode = 'auto';
            end
        end

        % Callback function
        function ZoomButtonValueChanged(app, event)
            value = app.ZoomButton.Value;
            value2 = app.ZoomButton_2.Value;
            value3 = app.ZoomButton_3.Value;
            val = value || value2 || value3;
            if val == 1
                zoom(app.signal_modulee,'on'); 
                zoom(app.message,'on');
                zoom(app.specter,'on');
                zoom(app.domainetemps,'on');
                zoom(app.domainefreq,'on');
            end
            if val == 0
                zoom(app.specter,'off');
                zoom(app.signal_modulee,'off'); 
                zoom(app.message,'off');
                zoom(app.domainetemps,'off');
                zoom(app.domainefreq,'off');
                
                app.domainetemps.XLimMode = 'auto';
                app.domainetemps.YLimMode = 'auto';
                app.domainetemps.ZLimMode = 'auto';  
                
                app.domainefreq.XLimMode = 'auto';
                app.domainefreq.YLimMode = 'auto';
                app.domainefreq.ZLimMode = 'auto';  
                
                app.signal_modulee.XLimMode = 'auto';
                app.signal_modulee.YLimMode = 'auto';
                app.signal_modulee.ZLimMode = 'auto';  
                
                app.message.XLimMode = 'auto';
                app.message.YLimMode = 'auto';
                app.message.ZLimMode = 'auto'; 
                
                app.specter.XLimMode = 'auto';
                app.specter.YLimMode = 'auto';
                app.specter.ZLimMode = 'auto';  
            end
        end

        % Selection changed function: modulationButtonGroup
        function modulationButtonGroupSelectionChanged(app, event)
            selectedButton = app.modulationButtonGroup.SelectedObject;
            if app.modulation == 0
                if selectedButton == app.figure_2_sans_port
                   set(app.taux_m,'Enable','off');
                end
                if selectedButton == app.figure_2_avec_port
                set(app.taux_m,'Enable','on');
                end
                return
            end
            Ap = app.amplitude_porteuse.Value;
            wp = 2*pi*app.frequence_porteuse.Value;
            m = app.taux_m.Value;
            plot_param = {'Color', [0.6 0.1 0.2],'Linewidth',1.5};
            if app.control_calcul_modulee == 1
            %if isempty(app.sport)
                app.sport = Ap*cos(wp*app.t) + Ap*m*cos(wp*app.t).*app.x;
                    %app.env_control = 1;
                    %app.control_calcul_fft = 1;
            %end
            %if isempty(app.snoport)
                app.snoport = Ap*cos(wp*app.t).*app.x;
                %app.env_control = 1;
                app.env_sport = abs(hilbert(app.sport));
                app.env_snoport = abs(hilbert(app.snoport));
                
                cla(app.signal_modulee) % pour vider la figure
                
                hold(app.signal_modulee,'on');
                app.plot_sport = plot(app.signal_modulee,app.t,app.sport);
                set(app.plot_sport,'Visible','off');
                
                app.plot_snoport = plot(app.signal_modulee,app.t,app.snoport);
                set(app.plot_snoport,'Visible','off');
                
                app.env_plot_sport = plot(app.signal_modulee,app.t,app.env_sport*[-1,1],plot_param{:});
                set(app.env_plot_sport,'Visible','off');
                app.env_plot_snoport = plot(app.signal_modulee,app.t,app.env_snoport*[-1,1],plot_param{:});
                set(app.env_plot_snoport,'Visible','off');
                
                
                hold(app.signal_modulee,'off');
                
                app.control_calcul_modulee = 0;
                %app.control_calcul_env = 0;
                app.control_calcul_fft = 1;
                app.control_calcul_demod = 1;
                
                enveloppeButtonValueChanged(app, event);
                tracer_spectreButtonPushed(app, event);
            end        
            %end
            if selectedButton == app.figure_2_avec_port
                set(app.taux_m,'Enable','on');
                set(app.env_plot_sport,'Visible','off');
                set(app.env_plot_snoport,'Visible','off');
                set(app.plot_snoport,'Visible','off');
                set(app.plot_sport,'Visible','on');
                %plot(app.signal_modulee,app.t,app.sport);
                %app.env_control2 = 1;
            end
            if selectedButton == app.figure_2_sans_port
                set(app.taux_m,'Enable','off');
                set(app.env_plot_sport,'Visible','off');
                set(app.env_plot_snoport,'Visible','off');
                set(app.plot_sport,'Visible','off');
                set(app.plot_snoport,'Visible','on');
                %plot(app.signal_modulee,app.t,app.snoport);
                %app.env_control2 = 1;
            end
            %enveloppeButtonValueChanged(app, event);
            %tracer_spectreButtonPushed(app, event);
        end

        % Value changed function: enveloppeButton
        function enveloppeButtonValueChanged(app, event)
            value = app.enveloppeButton.Value;
            %plot_param = {'Color', [0.6 0.1 0.2],'Linewidth',1.5};
            selectedButton = app.modulationButtonGroup.SelectedObject;
            if app.modulation == 0
                return
            end
            if value == 1
%                 if app.env_control == 1
%                 if app.control_calcul_env == 1
%                     app.env_sport = abs(hilbert(app.sport));
%                     app.env_snoport = abs(hilbert(app.snoport));
%                     %app.env_control = 0;
%                     app.control_calcul_env = 0;
%                 end
%                 hold(app.signal_modulee, 'on');
%                 if selectedButton == app.figure_2_avec_port
%                     app.env_plot = plot(app.signal_modulee,app.t,app.env_sport*[-1,1],plot_param{:});
%                 end
%                 if selectedButton == app.figure_2_sans_port
%                     app.env_plot = plot(app.signal_modulee,app.t,app.env_snoport*[-1,1],plot_param{:});
%                 end
%                 app.env_control2 = 0;
%                 hold(app.signal_modulee, 'off');
                if selectedButton == app.figure_2_avec_port
                    set(app.env_plot_snoport,'Visible','off');
                    set(app.env_plot_sport,'Visible','on');
                end
                if selectedButton == app.figure_2_sans_port
                    set(app.env_plot_sport,'Visible','off');
                    set(app.env_plot_snoport,'Visible','on');
                end
            end
            if value == 0
%                 if app.env_control == 1
%                     return
%                 end
%                 if app.env_control2 == 0
%                     set(app.env_plot,'Visible','off');
%                 end
                set(app.env_plot_sport,'Visible','off');
                set(app.env_plot_snoport,'Visible','off');
            end
        end

        % Value changed function: amplitude_porteuse, 
        % frequence_porteuse, taux_m
        function taux_mValueChanged(app, event)
            %app.sport = [];
            app.control_calcul_modulee = 1;
            modulationButtonGroupSelectionChanged(app, event);
        end

        % Selection changed function: optionsButtonGroup
        function optionsButtonGroupSelectionChanged(app, event)
            a = app.optionsButtonGroup.SelectedObject;
            if a == app.logic_chemin
                %app.modulation = 0;
                set(app.Dure,'Editable','off');
                set(app.Frequencedechantillonage,'Editable','off');
                set(app.Ecouterv,'Enable','on');
                set(app.EcouterSdepart,'Enable','on');
            end
            if a == app.logic_variable
                %app.modulation =0;
                set(app.Dure,'Editable','on');
                set(app.Frequencedechantillonage,'Editable','on');
                set(app.Ecouterv,'Enable','off');
                set(app.EcouterSdepart,'Enable','off');
            end
        end

        % Value changed function: Dure, Frequencedechantillonage
        function FrequencedechantillonageValueChanged(app, event)
            if app.modulation == 1
                app.Nombredepoints.Value = length(app.t);
                figure_1ButtonPushed(app, event);
            else
                app.Nombredepoints.Value = floor(app.Frequencedechantillonage.Value*app.Dure.Value);
            end
        end

        % Value changed function: type_mod
        function type_modValueChanged(app, event)
            value = app.type_mod.Value;          
            if value == 'modulation sans porteuse'
                 set(app.type_mod_bande,'Enable','on');
                 set(app.filtrSSBVSBCheckBox, 'Enable', 'on');
                 set(app.puissance_2, 'Enable', 'on');
            end
            if value == 'modulation avec porteuse'
                 set(app.type_mod_bande,'Enable','off');
                 set(app.filtrSSBVSBCheckBox, 'Enable', 'off');
                 set(app.puissance_2, 'Enable', 'off');
            end
            tracer_spectreButtonPushed(app,event);
        end

        % Callback function
        function tracer_spectreButtonPushed(app, event)
            if app.modulation == 0
                return
            end
            
            if app.control_calcul_fft == 1
                % calcul des spectres et puissances
                N = app.Nombredepoints.Value;
                Fr = app.Frequencedechantillonage.Value;
                f_port = app.frequence_porteuse.Value;
                app.f = (Fr/N*(-N/2:(N/2 - 1)))';
                
                app.spectre_signal = fftshift(fft(app.x));
                app.spectre_signal_sport = fftshift(fft(app.sport));
                app.spectre_signal_snoport = fftshift(fft(app.snoport));
                
                app.power_sport = bandpower(app.sport);
                [app.bandw,app.band_lo,app.band_hi,app.power_snoport] = obw(app.snoport,Fr);
                
                filtre_carre = @(fre) 1.*(abs(fre)<= app.band_hi & abs(fre)>= f_port);
                filtre1 = filtre_carre(app.f);
                app.spectre_signal_snoport_SSB = app.spectre_signal_snoport .* filtre1;
                
                
                temp = app.band_lo+app.bandw*0.3;
                a = 1/(f_port-temp) ; b = -temp*a;
                filtre_trapez = @(fre) 1.*(abs(fre)<= app.band_hi & abs(fre)>= f_port) + (a*abs(fre)+b).*(abs(fre) <f_port & abs(fre)>=temp); 
                %VSB avec 70% bandwidth total

                filtre2 = filtre_trapez(app.f);
                app.spectre_signal_snoport_VSB = app.spectre_signal_snoport .* filtre2;
                
                app.control_calcul_fft = 0;
                
                app.plot_spectre_signal = plot(app.specter,app.f,abs(app.spectre_signal),'green');
                
                %%%%%%% module + filtre en temps
                app.snoport_SSB = ifft(ifftshift(app.spectre_signal_snoport_SSB));
                app.power_snoport_SSB = bandpower(app.snoport_SSB);
                app.snoport_VSB = ifft(ifftshift(app.spectre_signal_snoport_VSB));
                app.power_snoport_VSB = bandpower(app.snoport_VSB);
                
                %%%% plots
                hold(app.specter,'on');
                app.plot_spectre_signal_sport = plot(app.specter,app.f,abs(app.spectre_signal_sport),'red');
                set(app.plot_spectre_signal_sport,'Visible','off');
                
                app.plot_spectre_signal_snoport = plot(app.specter,app.f,abs(app.spectre_signal_snoport),'blue');
                set(app.plot_spectre_signal_snoport,'Visible','off');
                
                app.plot_spectre_signal_snoport_SSB = plot(app.specter,app.f,abs(app.spectre_signal_snoport_SSB),'black');
                set(app.plot_spectre_signal_snoport_SSB,'Visible','off');
                
                app.plot_spectre_signal_snoport_VSB = plot(app.specter,app.f,abs(app.spectre_signal_snoport_VSB),'magenta');
                set(app.plot_spectre_signal_snoport_VSB,'Visible','off');
                
                hold(app.specter,'off');
                
                demodButtonPushed(app, event);
            end
            
            value = app.type_mod.Value;
            type = app.type_mod_bande.Value;
            if value == 'modulation sans porteuse'
                set(app.plot_spectre_signal_sport,'Visible','off');
                set(app.plot_spectre_signal_snoport,'Visible','on');
                set(app.puissance,'Value',app.power_snoport);
                if type == 'SSB(SC)'
                    set(app.plot_spectre_signal_snoport_VSB,'Visible','off');
                    set(app.plot_spectre_signal_snoport_SSB,'Visible','on');
                    set(app.bande,'Value',app.bandw*0.5);
                    set(app.puissance_2,'Value',app.power_snoport_SSB);
                end
                if type == 'VSB(SC)'
                    set(app.plot_spectre_signal_snoport_SSB,'Visible','off');
                    set(app.plot_spectre_signal_snoport_VSB,'Visible','on');
                    set(app.bande,'Value',app.bandw*0.7);
                    set(app.puissance_2,'Value',app.power_snoport_VSB);
                end
            end
            if value == 'modulation avec porteuse'
                set(app.plot_spectre_signal_snoport,'Visible','off');
                set(app.plot_spectre_signal_snoport_VSB,'Visible','off');
                set(app.plot_spectre_signal_snoport_SSB,'Visible','off');
                set(app.plot_spectre_signal_sport,'Visible','on');
                set(app.bande,'Value',app.bandw);
                set(app.puissance,'Value',app.power_sport);
            end 
            
            %demodButtonPushed(app, event);
        end

        % Value changed function: type_mod_bande
        function type_mod_bandeValueChanged(app, event)
            tracer_spectreButtonPushed(app,event);
        end

        % Value changed function: modulantCheckBox
        function modulantCheckBoxValueChanged(app, event)
            if app.modulation == 0 
                return
            end
            
            value = app.modulantCheckBox.Value;
            if value == 1
                set(app.plot_spectre_signal,'Visible','on');
            end
            if value == 0
                set(app.plot_spectre_signal,'Visible','off');
            end
        end

        % Value changed function: modulCheckBox
        function modulCheckBoxValueChanged(app, event)
            if app.modulation == 0 
                return
            end
            
            value = app.modulCheckBox.Value;
            type_modu = app.type_mod.Value;
            if value == 1
                if type_modu == 'modulation avec porteuse'
                    set(app.plot_spectre_signal_sport,'Visible','on');
                end
                if type_modu == 'modulation sans porteuse'
                    set(app.plot_spectre_signal_snoport,'Visible','on');
                end
            end
            if value == 0
                set(app.plot_spectre_signal_sport,'Visible','off');
                set(app.plot_spectre_signal_snoport,'Visible','off');
            end
            
        end

        % Value changed function: filtrSSBVSBCheckBox
        function filtrSSBVSBCheckBoxValueChanged(app, event)
            if app.modulation == 0 
                return
            end
            
            value = app.filtrSSBVSBCheckBox.Value;
            type = app.type_mod_bande.Value;
            if value == 1
                if type == 'SSB(SC)'
                    set(app.plot_spectre_signal_snoport_SSB,'Visible','on');
                end
                if type == 'VSB(SC)'
                    set(app.plot_spectre_signal_snoport_VSB,'Visible','on');
                end
            end
            if value == 0
                set(app.plot_spectre_signal_snoport_VSB,'Visible','off');
                set(app.plot_spectre_signal_snoport_SSB,'Visible','off');
            end
            
        end

        % Callback function
        function demodButtonPushed(app, event)
            if app.modulation == 0
                return
            end
            
            if app.control_calcul_demod == 1
                Ap = app.amplitude_porteuse.Value;
                Deltaf = app.Df.Value;
                Deltaphi = app.Dphi.Value*pi/180;
                f_port = app.frequence_porteuse.Value;
                % p pour porteuse ; np por noporteuse ; majuscule frequence, miniscule temps
                app.xnp = Ap*cos(2*pi*(f_port+Deltaf)*app.t + Deltaphi) .* app.snoport_VSB; 
                app.xp = Ap*cos(2*pi*(f_port+Deltaf)*app.t + Deltaphi) .* app.sport;
                
                app.Xnp = fftshift(fft(app.xnp));
                app.Xp = fftshift(fft(app.xp));
                
                filtre = @(fre) 1.*(abs(fre) <= app.bandw/2);
                filter = filtre(app.f);

                app.Ynp = app.Xnp .* filter;
                app.Yp = app.Xp .* filter;
                
                app.ynp = ifft(ifftshift(app.Ynp));
                app.yp = ifft(ifftshift(app.Yp));
                
                app.vnp = detrend(app.ynp);
                app.vp = detrend(app.yp);
                
                app.Vnp = abs(fftshift(fft(app.vnp)));
                app.Vp = abs(fftshift(fft(app.vp)));
                
                %plot(app.t, app.vnp) ;
                %sound(real(app.vnp),app.Frequencedechantillonage.Value);
                

                 cla(app.domainetemps);
                 cla(app.domainefreq);
%                 
                 hold(app.domainetemps,'on');
%                 
                app.plot_snp = plot(app.domainetemps, app.t,app.snoport_VSB);
                set(app.plot_snp,'Visible','off'); 
                app.plot_sp = plot(app.domainetemps, app.t,app.sport);
                set(app.plot_sp,'Visible','off');
                
                app.plot_xnp = plot(app.domainetemps, app.t,app.xnp);
                set(app.plot_xnp,'Visible','off');
                app.plot_xp = plot(app.domainetemps, app.t,app.xp);
                set(app.plot_xp,'Visible','off');
                
                app.plot_ynp = plot(app.domainetemps, app.t,app.ynp);
                set(app.plot_ynp,'Visible','off');
                app.plot_yp = plot(app.domainetemps, app.t,app.yp);
                set(app.plot_yp,'Visible','off');
                
                app.plot_vnp = plot(app.domainetemps, app.t,app.vnp);
                set(app.plot_vnp,'Visible','off');
                app.plot_vp = plot(app.domainetemps, app.t,app.vp);
                set(app.plot_vp,'Visible','off');
                
                app.plot_x = plot(app.domainetemps, app.t,app.x);
                set(app.plot_x,'Visible','off');
                
                hold(app.domainetemps,'off');
%                 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                hold(app.domainefreq,'on');
                
                app.plot_Snp = plot(app.domainefreq, app.f,abs(app.spectre_signal_snoport_VSB));
                set(app.plot_Snp,'Visible','off');
                app.plot_Sp = plot(app.domainefreq, app.f,abs(app.spectre_signal_sport));
                set(app.plot_Sp,'Visible','off');
                
                app.plot_Xnp = plot(app.domainefreq, app.f,abs(app.Xnp));
                set(app.plot_Xnp,'Visible','off');
                app.plot_Xp = plot(app.domainefreq, app.f,abs(app.Xp));
                set(app.plot_Xp,'Visible','off');
                
                app.plot_Ynp = plot(app.domainefreq, app.f,abs(app.Ynp));
                set(app.plot_Ynp,'Visible','off');
                app.plot_Yp = plot(app.domainefreq, app.f,abs(app.Yp));
                set(app.plot_Yp,'Visible','off');
                
                app.plot_Vnp = plot(app.domainefreq, app.f,app.Vnp);
                set(app.plot_Vnp,'Visible','off');
                app.plot_Vp = plot(app.domainefreq, app.f,app.Vp);
                set(app.plot_Vp,'Visible','off');
                
                app.plot_X = plot(app.domainefreq, app.f,abs(app.spectre_signal));
                set(app.plot_X,'Visible','off');
                
                hold(app.domainefreq,'off');
%                 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                 
                app.control_calcul_demod = 0;             
            end
            
            Val = app.type_mod_2.Value;
            
            if Val == 'modulation sans porteuse (VSB)'
                
                set(app.plot_snp,'Visible','on');
                set(app.plot_sp,'Visible','off');
                set(app.plot_xnp,'Visible','on');
                set(app.plot_xp,'Visible','off');
                set(app.plot_ynp,'Visible','on');
                set(app.plot_yp,'Visible','off');
                set(app.plot_vnp,'Visible','on');
                set(app.plot_vp,'Visible','off');
                set(app.plot_x,'Visible','on');
                
                
                set(app.plot_Snp,'Visible','on');
                set(app.plot_Sp,'Visible','off');
                set(app.plot_Xnp,'Visible','on');
                set(app.plot_Xp,'Visible','off');
                set(app.plot_Ynp,'Visible','on');
                set(app.plot_Yp,'Visible','off');
                set(app.plot_Vnp,'Visible','on');
                set(app.plot_Vp,'Visible','off');
                set(app.plot_X,'Visible','on');
            end
            if Val =='modulation avec porteuse (---)'
                
                set(app.plot_snp,'Visible','off');
                set(app.plot_sp,'Visible','on');
                set(app.plot_xnp,'Visible','off');
                set(app.plot_xp,'Visible','on');
                set(app.plot_ynp,'Visible','off');
                set(app.plot_yp,'Visible','on');
                set(app.plot_vnp,'Visible','off');
                set(app.plot_vp,'Visible','on');
                set(app.plot_x,'Visible','on');
                
                
                set(app.plot_Snp,'Visible','off');
                set(app.plot_Sp,'Visible','on');
                set(app.plot_Xnp,'Visible','off');
                set(app.plot_Xp,'Visible','on');
                set(app.plot_Ynp,'Visible','off');
                set(app.plot_Yp,'Visible','on');
                set(app.plot_Vnp,'Visible','off');
                set(app.plot_Vp,'Visible','on');
                set(app.plot_X,'Visible','on');
                
            end


        end

        % Value changed function: sCheckBox
        function sCheckBoxValueChanged(app, event)
            if app.modulation == 0 
                return
            end
            value = app.sCheckBox.Value;
            type = app.type_mod_2.Value;
            if value == 1
                if type == 'modulation sans porteuse (VSB)'
                    set(app.plot_Snp,'Visible','on');
                    set(app.plot_snp,'Visible','on');
                end
                if type == 'modulation avec porteuse (---)'
                    set(app.plot_sp,'Visible','on');
                    set(app.plot_Sp,'Visible','on');
                end
            end
            if value == 0
                set(app.plot_snp,'Visible','off');
                set(app.plot_sp,'Visible','off');
                set(app.plot_Snp,'Visible','off');
                set(app.plot_Sp,'Visible','off');
            end
        end

        % Value changed function: xCheckBox
        function xCheckBoxValueChanged(app, event)
            if app.modulation == 0 
                return
            end
            value = app.xCheckBox.Value;
            type = app.type_mod_2.Value;
            if value == 1
                if type == 'modulation sans porteuse (VSB)'
                    set(app.plot_Xnp,'Visible','on');
                    set(app.plot_xnp,'Visible','on');
                end
                if type == 'modulation avec porteuse (---)'
                    set(app.plot_xp,'Visible','on');
                    set(app.plot_Xp,'Visible','on');
                end
            end
            if value == 0
                set(app.plot_xnp,'Visible','off');
                set(app.plot_xp,'Visible','off');
                set(app.plot_Xnp,'Visible','off');
                set(app.plot_Xp,'Visible','off');
            end
        end

        % Value changed function: yCheckBox
        function yCheckBoxValueChanged(app, event)
            if app.modulation == 0 
                return
            end
            value = app.yCheckBox.Value;
            type = app.type_mod_2.Value;
            if value == 1
                if type == 'modulation sans porteuse (VSB)'
                    set(app.plot_Ynp,'Visible','on');
                    set(app.plot_ynp,'Visible','on');
                end
                if type == 'modulation avec porteuse (---)'
                    set(app.plot_yp,'Visible','on');
                    set(app.plot_Yp,'Visible','on');
                end
            end
            if value == 0
                set(app.plot_ynp,'Visible','off');
                set(app.plot_yp,'Visible','off');
                set(app.plot_Ynp,'Visible','off');
                set(app.plot_Yp,'Visible','off');
            end
        end

        % Value changed function: vCheckBox
        function vCheckBoxValueChanged(app, event)
            if app.modulation == 0 
                return
            end
            value = app.vCheckBox.Value;
            type = app.type_mod_2.Value;
            if value == 1
                if type == 'modulation sans porteuse (VSB)'
                    set(app.plot_Vnp,'Visible','on');
                    set(app.plot_vnp,'Visible','on');
                end
                if type == 'modulation avec porteuse (---)'
                    set(app.plot_Vp,'Visible','on');
                    set(app.plot_vp,'Visible','on');
                end
            end
            if value == 0
                set(app.plot_vnp,'Visible','off');
                set(app.plot_vp,'Visible','off');
                set(app.plot_Vnp,'Visible','off');
                set(app.plot_Vp,'Visible','off');
            end
        end

        % Value changed function: departCheckBox
        function departCheckBoxValueChanged(app, event)
            if app.modulation == 0 
                return
            end
            value = app.departCheckBox.Value;
            if value == 1
                set(app.plot_x,'Visible','on');
                set(app.plot_X,'Visible','on');
            end
            if value == 0
                set(app.plot_x,'Visible','off');
                set(app.plot_X,'Visible','off');
            end
        end

        % Button pushed function: Ecouterv
        function EcoutervButtonPushed(app, event)
            Fr = app.Frequencedechantillonage.Value;
            type = app.type_mod_2.Value;
            if type == 'modulation sans porteuse (VSB)'
                sound(app.vnp,Fr);
            end
            if type == 'modulation avec porteuse (---)'
                sound(app.vp,Fr);
            end
        end

        % Button pushed function: EcouterSdepart
        function EcouterSdepartButtonPushed(app, event)
            Fr = app.Frequencedechantillonage.Value;
            sound(app.x,Fr);
        end

        % Value changed function: type_mod_2
        function type_mod_2ValueChanged(app, event)
            demodButtonPushed(app, event);
        end

        % Value changed function: Df, Dphi
        function DfValueChanged(app, event)
            app.control_calcul_demod = 1;
            demodButtonPushed(app, event);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            m = sprintf('Les bouttons pour écouter les deux signaux sont disponible lors de la selection de l''option ''lire un fichier'' mais pas pour une fonction mathematique.');
            uialert(app.UIFigure,m,'Info','Icon','Info');
        end

        % Value changed function: Nombredepoints
        function NombredepointsValueChanged(app, event)
            value = app.Nombredepoints.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.domainetemporelTab = uitab(app.TabGroup);
            app.domainetemporelTab.Title = 'domaine temporel';
            app.IntroduirelemessageLabel = uilabel(app.domainetemporelTab);
            app.IntroduirelemessageLabel.Text = 'Introduire le message:';
            app.optionsButtonGroup = uibuttongroup(app.domainetemporelTab);
            app.optionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @optionsButtonGroupSelectionChanged, true);
            app.optionsButtonGroup.Title = 'options:';
            app.logic_variable = uiradiobutton(app.optionsButtonGroup);
            app.logic_variable.Text = 'fonction declarée';
            app.logic_variable.Value = true;
            app.logic_chemin = uiradiobutton(app.optionsButtonGroup);
            app.logic_chemin.Text = 'lire d''un fichier';
            app.chemin_fichier_OUvariable = uitextarea(app.domainetemporelTab);
            app.chemin_fichier_OUvariable.Value = {'chemin:'; 'E:\cours td 2\traitement\tp\tp video\tp5.wav'; ''; 'OU fonction de t:'; ''; 'cos(2*pi*t)+sin(4*pi*t)'; ''; ''};
            app.frequencedelaporteuseHzLabel = uilabel(app.domainetemporelTab);
            app.frequencedelaporteuseHzLabel.Text = 'frequence de la porteuse (Hz)';
            app.frequence_porteuse = uieditfield(app.domainetemporelTab, 'numeric');
            app.frequence_porteuse.Limits = [5 Inf];
            app.frequence_porteuse.ValueChangedFcn = createCallbackFcn(app, @taux_mValueChanged, true);
            app.frequence_porteuse.Value = 20;
            app.tauxdemodulationEditFieldLabel = uilabel(app.domainetemporelTab);
            app.tauxdemodulationEditFieldLabel.Text = 'taux de modulation';
            app.taux_m = uieditfield(app.domainetemporelTab, 'numeric');
            app.taux_m.Limits = [0 Inf];
            app.taux_m.ValueChangedFcn = createCallbackFcn(app, @taux_mValueChanged, true);
            app.taux_m.Value = 0.5;
            app.amplitudedelaporteuseEditFieldLabel = uilabel(app.domainetemporelTab);
            app.amplitudedelaporteuseEditFieldLabel.Text = 'amplitude de la porteuse';
            app.amplitude_porteuse = uieditfield(app.domainetemporelTab, 'numeric');
            app.amplitude_porteuse.Limits = [0 Inf];
            app.amplitude_porteuse.ValueChangedFcn = createCallbackFcn(app, @taux_mValueChanged, true);
            app.amplitude_porteuse.Value = 2;
            app.figure_1 = uibutton(app.domainetemporelTab, 'push');
            app.figure_1.ButtonPushedFcn = createCallbackFcn(app, @figure_1ButtonPushed, true);
            app.figure_1.Text = 'tracer le message';
            app.TabGroup2 = uitabgroup(app.domainetemporelTab);
            app.messageTab = uitab(app.TabGroup2);
            app.messageTab.Title = 'message';
            app.message = uiaxes(app.messageTab);
            title(app.message, 'message')
            xlabel(app.message, 't')
            app.signalsecondairemodulTab = uitab(app.TabGroup2);
            app.signalsecondairemodulTab.Title = 'signal secondaire modulé';
            app.signal_modulee = uiaxes(app.signalsecondairemodulTab);
            title(app.signal_modulee, 'signal secondaire modulé')
            xlabel(app.signal_modulee, 't')
            app.modulationButtonGroup = uibuttongroup(app.domainetemporelTab);
            app.modulationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @modulationButtonGroupSelectionChanged, true);
            app.modulationButtonGroup.Title = 'modulation:';
            app.figure_2_sans_port = uitogglebutton(app.modulationButtonGroup);
            app.figure_2_sans_port.Text = 'modulation sans porteuse';
            app.figure_2_avec_port = uitogglebutton(app.modulationButtonGroup);
            app.figure_2_avec_port.Text = 'modulation avec porteuse';
            app.figure_2_avec_port.Value = true;
            app.enveloppeButton = uibutton(app.domainetemporelTab, 'state');
            app.enveloppeButton.ValueChangedFcn = createCallbackFcn(app, @enveloppeButtonValueChanged, true);
            app.enveloppeButton.Text = 'enveloppe';
            app.Label = uilabel(app.domainetemporelTab);
            app.Label.Text = 'Etude de la modulation d''un signal quelconque dans le domaine temporel:';
            app.DuresLabel = uilabel(app.domainetemporelTab);
            app.DuresLabel.Text = 'Durée (s)';
            app.Dure = uieditfield(app.domainetemporelTab, 'numeric');
            app.Dure.Limits = [0 Inf];
            app.Dure.ValueChangedFcn = createCallbackFcn(app, @FrequencedechantillonageValueChanged, true);
            app.Dure.Value = 2;
            app.FrequencedechantHzLabel = uilabel(app.domainetemporelTab);
            app.FrequencedechantHzLabel.Text = 'Frequence d''echant. (Hz)';
            app.Frequencedechantillonage = uieditfield(app.domainetemporelTab, 'numeric');
            app.Frequencedechantillonage.Limits = [0 Inf];
            app.Frequencedechantillonage.ValueChangedFcn = createCallbackFcn(app, @FrequencedechantillonageValueChanged, true);
            app.Frequencedechantillonage.Value = 512;
            app.NombredepointsEditFieldLabel = uilabel(app.domainetemporelTab);
            app.NombredepointsEditFieldLabel.Text = 'Nombre de points';
            app.Nombredepoints = uieditfield(app.domainetemporelTab, 'numeric');
            app.Nombredepoints.ValueChangedFcn = createCallbackFcn(app, @NombredepointsValueChanged, true);
            app.Nombredepoints.Editable = 'off';
            app.Nombredepoints.Value = 1024;
            app.domainefrequentielTab = uitab(app.TabGroup);
            app.domainefrequentielTab.Title = 'domaine frequentiel';
            app.specter = uiaxes(app.domainefrequentielTab);
            title(app.specter, 'Spectre')
            xlabel(app.specter, 'f')
            app.Label_3 = uilabel(app.domainefrequentielTab);
            app.Label_3.Text = 'Etude du signal modulé dans le domaine fréquentiel:';
            app.BandeenvoyeHzLabel = uilabel(app.domainefrequentielTab);
            app.BandeenvoyeHzLabel.Text = 'Bande envoyée (Hz)';
            app.bande = uieditfield(app.domainefrequentielTab, 'numeric');
            app.bande.Limits = [0 Inf];
            app.bande.Editable = 'off';
            app.PuissanceSCFCWLabel = uilabel(app.domainefrequentielTab);
            app.PuissanceSCFCWLabel.Text = 'Puissance (SC-FC) (W)';
            app.puissance = uieditfield(app.domainefrequentielTab, 'numeric');
            app.puissance.Limits = [0 Inf];
            app.puissance.Editable = 'off';
            app.type_mod = uidropdown(app.domainefrequentielTab);
            app.type_mod.Items = {'modulation sans porteuse', 'modulation avec porteuse'};
            app.type_mod.ValueChangedFcn = createCallbackFcn(app, @type_modValueChanged, true);
            app.type_mod.Value = 'modulation sans porteuse';
            app.type_mod_bande = uidropdown(app.domainefrequentielTab);
            app.type_mod_bande.Items = {'SSB(SC)', 'VSB(SC)'};
            app.type_mod_bande.ValueChangedFcn = createCallbackFcn(app, @type_mod_bandeValueChanged, true);
            app.type_mod_bande.Value = 'SSB(SC)';
            app.modulantCheckBox = uicheckbox(app.domainefrequentielTab);
            app.modulantCheckBox.ValueChangedFcn = createCallbackFcn(app, @modulantCheckBoxValueChanged, true);
            app.modulantCheckBox.Text = 'modulant';
            app.modulantCheckBox.Value = true;
            app.modulCheckBox = uicheckbox(app.domainefrequentielTab);
            app.modulCheckBox.ValueChangedFcn = createCallbackFcn(app, @modulCheckBoxValueChanged, true);
            app.modulCheckBox.Text = 'modulé';
            app.modulCheckBox.Value = true;
            app.filtrSSBVSBCheckBox = uicheckbox(app.domainefrequentielTab);
            app.filtrSSBVSBCheckBox.ValueChangedFcn = createCallbackFcn(app, @filtrSSBVSBCheckBoxValueChanged, true);
            app.filtrSSBVSBCheckBox.Text = 'filtré(SSB/VSB)';
            app.filtrSSBVSBCheckBox.Value = true;
            app.PuissanceSSBVSBWLabel = uilabel(app.domainefrequentielTab);
            app.PuissanceSSBVSBWLabel.Text = 'Puissance (SSB-VSB) (W)';
            app.puissance_2 = uieditfield(app.domainefrequentielTab, 'numeric');
            app.puissance_2.Limits = [0 Inf];
            app.puissance_2.Editable = 'off';
            app.demodulationTab = uitab(app.TabGroup);
            app.demodulationTab.Title = 'demodulation';
            app.Label_4 = uilabel(app.demodulationTab);
            app.Label_4.Text = 'Démodulation : detection synchrone.';
            app.Button = uibutton(app.demodulationTab, 'push');
            app.Button.Icon = 'detection_synchrone.png';
            app.Button.Enable = 'off';
            app.Button.Text = '.';
            app.stLabel = uilabel(app.demodulationTab);
            app.stLabel.Text = 's(t)';
            app.xtLabel = uilabel(app.demodulationTab);
            app.xtLabel.Text = 'x(t)';
            app.ytLabel = uilabel(app.demodulationTab);
            app.ytLabel.Text = 'y(t)';
            app.vtLabel = uilabel(app.demodulationTab);
            app.vtLabel.Text = 'v(t)';
            app.Apcos2pifpDfDphiLabel = uilabel(app.demodulationTab);
            app.Apcos2pifpDfDphiLabel.Text = 'Ap*cos(2*pi*(fp+Df) + Dphi)';
            app.DfHzLabel = uilabel(app.demodulationTab);
            app.DfHzLabel.Text = 'Df(Hz)  ';
            app.Df = uieditfield(app.demodulationTab, 'numeric');
            app.Df.Limits = [0 Inf];
            app.Df.ValueChangedFcn = createCallbackFcn(app, @DfValueChanged, true);
            app.DphiLabel = uilabel(app.demodulationTab);
            app.DphiLabel.Text = 'Dphi(°) ';
            app.Dphi = uieditfield(app.demodulationTab, 'numeric');
            app.Dphi.ValueChangedFcn = createCallbackFcn(app, @DfValueChanged, true);
            app.type_mod_2 = uidropdown(app.demodulationTab);
            app.type_mod_2.Items = {'modulation sans porteuse (VSB)', 'modulation avec porteuse (---)'};
            app.type_mod_2.ValueChangedFcn = createCallbackFcn(app, @type_mod_2ValueChanged, true);
            app.type_mod_2.Value = 'modulation sans porteuse (VSB)';
            app.CocherpourafficherlessignauxetleursLabel = uilabel(app.demodulationTab);
            app.CocherpourafficherlessignauxetleursLabel.Text = 'Cocher pour afficher les signaux et leurs';
            app.TabGroup3 = uitabgroup(app.demodulationTab);
            app.DomainetemporelTab = uitab(app.TabGroup3);
            app.DomainetemporelTab.Title = 'Domaine temporel';
            app.domainetemps = uiaxes(app.DomainetemporelTab);
            title(app.domainetemps, 'Les signaux')
            xlabel(app.domainetemps, 't')
            app.DomainefrquentielTab = uitab(app.TabGroup3);
            app.DomainefrquentielTab.Title = 'Domaine fréquentiel';
            app.domainefreq = uiaxes(app.DomainefrquentielTab);
            title(app.domainefreq, 'Spectres')
            xlabel(app.domainefreq, 'f')
            app.sCheckBox = uicheckbox(app.demodulationTab);
            app.sCheckBox.ValueChangedFcn = createCallbackFcn(app, @sCheckBoxValueChanged, true);
            app.sCheckBox.Text = 's';
            app.sCheckBox.Value = true;
            app.xCheckBox = uicheckbox(app.demodulationTab);
            app.xCheckBox.ValueChangedFcn = createCallbackFcn(app, @xCheckBoxValueChanged, true);
            app.xCheckBox.Text = 'x';
            app.xCheckBox.Value = true;
            app.yCheckBox = uicheckbox(app.demodulationTab);
            app.yCheckBox.ValueChangedFcn = createCallbackFcn(app, @yCheckBoxValueChanged, true);
            app.yCheckBox.Text = 'y';
            app.yCheckBox.Value = true;
            app.vCheckBox = uicheckbox(app.demodulationTab);
            app.vCheckBox.ValueChangedFcn = createCallbackFcn(app, @vCheckBoxValueChanged, true);
            app.vCheckBox.Text = 'v';
            app.vCheckBox.Value = true;
            app.Ecouterv = uibutton(app.demodulationTab, 'push');
            app.Ecouterv.ButtonPushedFcn = createCallbackFcn(app, @EcoutervButtonPushed, true);
            app.Ecouterv.Enable = 'off';
            app.Ecouterv.Text = 'Ecouter v(t)';
            app.EcouterSdepart = uibutton(app.demodulationTab, 'push');
            app.EcouterSdepart.ButtonPushedFcn = createCallbackFcn(app, @EcouterSdepartButtonPushed, true);
            app.EcouterSdepart.Enable = 'off';
            app.EcouterSdepart.Text = 'Ecouter S.depart';
            app.departCheckBox = uicheckbox(app.demodulationTab);
            app.departCheckBox.ValueChangedFcn = createCallbackFcn(app, @departCheckBoxValueChanged, true);
            app.departCheckBox.Text = 'depart';
            app.departCheckBox.Value = true;
            app.spectreslorsdeladmodulationLabel = uilabel(app.demodulationTab);
            app.spectreslorsdeladmodulationLabel.Text = 'spectres lors de la démodulation:';
            app.Button_4 = uibutton(app.demodulationTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface_Modulation_Amplitude

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end